classdef Hashirama < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PortLabel                 matlab.ui.control.Label
        PortsDropDown             matlab.ui.control.DropDown
        TabGroup                  matlab.ui.container.TabGroup
        MonitorTab                matlab.ui.container.Tab
        ModeLabel                 matlab.ui.control.Label
        DurationmsLabel           matlab.ui.control.Label
        FsHzLabel_2               matlab.ui.control.Label
        TimeScaleLabel            matlab.ui.control.Label
        mVLabel_3                 matlab.ui.control.Label
        mVLabel_2                 matlab.ui.control.Label
        M_FsHzEditField           matlab.ui.control.NumericEditField
        M_DurationEditField       matlab.ui.control.NumericEditField
        M_ModeDropDown            matlab.ui.control.DropDown
        M_TimeScaleDropDown       matlab.ui.control.DropDown
        M_CLEARButton             matlab.ui.control.Button
        M_RECORDButton            matlab.ui.control.Button
        M_SAVEButton              matlab.ui.control.Button
        M_RUNSTOPButton           matlab.ui.control.Button
        M_In_Slider               matlab.ui.control.RangeSlider
        M_Out_Slider              matlab.ui.control.RangeSlider
        PPmVLabel_3               matlab.ui.control.Label
        AvgmVLabel_3              matlab.ui.control.Label
        MinmVLabel_3              matlab.ui.control.Label
        MaxmVLabel_3              matlab.ui.control.Label
        M_Out_AvgmVEditField      matlab.ui.control.NumericEditField
        M_Out_MaxmVEditField      matlab.ui.control.NumericEditField
        M_Out_MinmVEditField      matlab.ui.control.NumericEditField
        M_Out_PPAmpmVEditField    matlab.ui.control.NumericEditField
        OutputLabel               matlab.ui.control.Label
        PPmVLabel_2               matlab.ui.control.Label
        AvgmVLabel_2              matlab.ui.control.Label
        MinmVLabel_2              matlab.ui.control.Label
        MaxmVLabel_2              matlab.ui.control.Label
        M_In_AvgmVEditField       matlab.ui.control.NumericEditField
        M_In_MaxmVEditField       matlab.ui.control.NumericEditField
        M_In_MinmVEditField       matlab.ui.control.NumericEditField
        M_In_PPAmpmVEditField     matlab.ui.control.NumericEditField
        InputLabel                matlab.ui.control.Label
        M_RecordLamp              matlab.ui.control.Lamp
        M_Out_Plot                matlab.ui.control.UIAxes
        M_In_Plot                 matlab.ui.control.UIAxes
        UploaderTab               matlab.ui.container.Tab
        mVLabel                   matlab.ui.control.Label
        msLabel                   matlab.ui.control.Label
        UL_ClearButton            matlab.ui.control.Button
        UL_Slider                 matlab.ui.control.RangeSlider
        StdmsLabel                matlab.ui.control.Label
        AvgmsLabel                matlab.ui.control.Label
        MinmsLabel                matlab.ui.control.Label
        MaxmsLabel                matlab.ui.control.Label
        UL_MaxmsEditField         matlab.ui.control.NumericEditField
        UL_AvgmsEditField         matlab.ui.control.NumericEditField
        UL_StdmsEditField         matlab.ui.control.NumericEditField
        UL_MinmsEditField         matlab.ui.control.NumericEditField
        IntervalLabel             matlab.ui.control.Label
        PPmVLabel                 matlab.ui.control.Label
        AvgmVLabel                matlab.ui.control.Label
        MinmVLabel                matlab.ui.control.Label
        MaxmVLabel                matlab.ui.control.Label
        UL_AvgmVEditField         matlab.ui.control.NumericEditField
        UL_MaxmVEditField         matlab.ui.control.NumericEditField
        UL_MinmVEditField         matlab.ui.control.NumericEditField
        UL_PPAmpmVEditField       matlab.ui.control.NumericEditField
        AmplitudeLabel            matlab.ui.control.Label
        DurationLabel             matlab.ui.control.Label
        FsHzLabel                 matlab.ui.control.Label
        UL_SamplingRateEditField  matlab.ui.control.NumericEditField
        SamplingRateLabel         matlab.ui.control.Label
        tmsLabel_2                matlab.ui.control.Label
        UL_DurationEditField      matlab.ui.control.NumericEditField
        UL_FlushButton            matlab.ui.control.Button
        UL_SearchButton           matlab.ui.control.Button
        UL_UploadButton           matlab.ui.control.Button
        UL_FileEditField          matlab.ui.control.EditField
        FileEditFieldLabel        matlab.ui.control.Label
        UL_Plot                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Philosophy of Project Senju Hashirama:
        % Maximize the precision
        % 1. Temporal precision
        %    - All timestamps are generated from Senju.
        %    - Minimize the influence of the GUI event handling,
        %      maximize the GUI event disable when timers are working.
        % 2. Voltage precision
        %    - Real-time LPF

        %% Structure of the program
        % Modes of plot initialization
        % 1. resetMarks()             : marks
        % 2. resetMarks() + slider=   : marks              + slider + gaugeToVisible
        % 3. cla() + slider= +gauge   : marks + plot       + slider + gaugeToZero 
        % 4. cla() + slider= +gauge   : marks + plot +data + slider + gaugeToZero 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Device
        device 
        default_port_message='Select a port'
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Timer
        dataTimer 
        processTimer
        validationTimer
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Seeeduino Specification
        MAX_V_mV =3300
        MAX_IN_V_NUM_OF_BIN = 4095
        MAX_OUT_V_NUM_OF_BIN = 1023
        MAX_NUM_SAMPLE = 20000
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Communication
        SIG_UPLOAD = 0x554C     % UL
        SIG_MONITOR = 0x4D4E  % MN
        SIG_IMPULSE = 0x4950  % IP
        SIG_FLUSH = 0x464C    % FL
        SIG_IDLE = 0x4944     % ID
        SIG_DUMMY = 0xFFFF
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Monitor
        M_Disp_In_vBuf = []
        M_Disp_Out_vBuf = []
        M_Disp_tBuf = []

        M_Save_In_vBuf = []
        M_Save_Out_vBuf =[]
        M_Save_tBuf = []
        
        M_In_Enable logical = true
        M_Out_Enable logical =false

        M_In_srt_vline = []
        M_In_end_vline = []
        M_In_shadedArea = []

        M_Out_srt_vline = []
        M_Out_end_vline = []
        M_Out_shadedArea = []
        
        showLength = 100000 % Number of points to show on the plot

        % RUN/STOP Btn Click
        isRunning logical = true % Flag to updating the plot

        % RECORD Btn
        isRecording logical = false

        % IR Measurement
        isIRmode logical = false

        % Zoom
        M_tTickSize
        M_tTickNumDisp=10
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Uploader
        UL_vBuf = [] 
        UL_tBuf = []

        UL_srt_vline
        UL_end_vline
        UL_shadedArea
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        INTERVAL_MS=2
    end
    
    methods (Access = private)
        %% Thread
        function acquireData(app)
            if ~isempty(app.device)
                try
                    datMat=app.oneTimeRead(); 
                catch
                    return
                end
                 if isempty(datMat)
                    return
                end
                ts = datMat(:,1); vins=datMat(:,2); vouts=datMat(:,3);

                if app.isRunning
                    app.M_Disp_tBuf = [app.M_Disp_tBuf; ts];
                    if app.M_In_Enable
                        app.M_Disp_In_vBuf = [app.M_Disp_In_vBuf; vins];
                    end
                    if app.M_Out_Enable
                        app.M_Disp_Out_vBuf = [app.M_Disp_Out_vBuf; vouts];
                    end
                end

                if app.isRecording
                    app.M_Save_tBuf=[app.M_Save_tBuf; ts];
                    if app.M_In_Enable
                        app.M_Save_In_vBuf=[app.M_Save_In_vBuf; vins];
                    end
                    if app.M_Out_Enable
                        app.M_Save_Out_vBuf=[app.M_Save_Out_vBuf; vouts];
                    end
                end
            end
        end
        
        function updatePlot(app)
            if(app.isRunning && ~isempty(app.M_Disp_tBuf))
                if length(app.M_Disp_tBuf) > app.showLength
                    app.M_Disp_tBuf = app.M_Disp_tBuf(end-app.showLength+1:end);
                    if app.M_In_Enable
                        app.M_Disp_In_vBuf = app.M_Disp_In_vBuf(end-app.showLength+1:end);
                    end
                    if app.M_Out_Enable
                        app.M_Disp_Out_vBuf = app.M_Disp_Out_vBuf(end-app.showLength+1:end);
                    end
                end
                
                vis_ts = []; vis_vins = []; vis_vouts = [];
                [vis_ts, vis_vins, vis_vouts] = app.M_plot();
                
                % Edit the features
                app.M_NumUpdate(vis_ts ,vis_vins, vis_vouts);
            end
        end
        
        function validateDevice(app)
            try
                write(app.device, "ping", "uint16");
            catch
                app.timerSwitch(app.validationTimer, false);
                app.timerSwitch(app.dataTimer, false);
                app.timerSwitch(app.processTimer, false);
                delete(app.device);
                app.device = [];
                app.PortsDropDown.Value = app.default_port_message;
                uialert(app.UIFigure, 'Device disconnected.', 'Device Error');
            end
        end


        %%
        function stateChangeNotify(app, arg)
            % Ensure that arg is a uint16 for safety
            if ~isa(arg, 'uint16')
                error('Argument must be a uint16');
            end
            
            try
                pause(0.1);  % Wait a moment before sending
                write(app.device, arg, "uint16");
                pause(0.1);  % Wait a moment after sending
            catch
                uialert(app.UIFigure,'Device disconnected.','Device Error' );
                app.PortsDropDown.Value = app.default_port_message;
            end
        end


        %% CSV
       function saveCSV(app, ts, vins, vouts)
            % Open a file save dialog allowing the user to specify a CSV file
            [file, path] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save as');
            if isequal(file, 0) || isequal(path, 0)
                disp('User clicked Cancel.');
            else
                % Construct the full path where the file will be saved
                fullPath = fullfile(path, file);
                disp(['User selected ', fullPath, ' for saving.']);
        
                % Check and prepare data
                if isempty(vins) && isempty(vouts)
                    uialert(app.UIFigure, 'No data available to save.', 'Save Error');
                    return;
                end
                
                % Initialize table data
                ts = ts(:); % Ensure ts is a column vector
                data = {ts};
                varNames = {'Time'};
                
                if ~isempty(vins)
                    vins = vins(:); % Ensure vins is a column vector
                    data = [data, {vins}];
                    varNames = [varNames, {'Volt_In'}];
                end
                if ~isempty(vouts)
                    vouts = vouts(:); % Ensure vouts is a column vector
                    data = [data, {vouts}];
                    varNames = [varNames, {'Volt_Out'}];
                end
        
                % Create the table from the arrays
                T = table(data{:}, 'VariableNames', varNames);
        
                % Write the table to a CSV file
                writetable(T, fullPath);
                disp(['Data saved to ', fullPath]);
            end
        end


        function [time, volt] = readCSV(app, csv_path)
            % Define keywords to identify the time and voltage columns
            TIME_INDICATORS = {'time', 'sec', 'stamp'};
            VOLT_INDICATORS = {'volt', 'amp', 'meas'};
            
            % Read the CSV file. This assumes the file has a header row.
            opts = detectImportOptions(csv_path);
            data = readtable(csv_path, opts);
            
            % Initialize output variables
            time = [];
            volt = [];
            
            % Loop over all column names to find matches for time and voltage
            for i = 1:length(opts.VariableNames)
                varName = lower(opts.VariableNames{i});  % Convert to lowercase to ensure case-insensitivity
                % Check if the column name contains any of the time indicators
                if any(contains(varName, TIME_INDICATORS))
                    time = data{:, i};
                % Check if the column name contains any of the voltage indicators
                elseif any(contains(varName, VOLT_INDICATORS))
                    volt = data{:, i};
                end
            end
            
            % Check if time or volt columns were found
            if isempty(time)
                error('Time column not found in the CSV file.');
            end
            if isempty(volt)
                error('Voltage column not found in the CSV file.');
            end
        end

        %% Plot Selection
        function M_resetMarks_withoutSliderUpdate(app)
            % Input plot reset
            if ~isempty(app.M_In_srt_vline)
                delete(app.M_In_srt_vline);
                app.M_In_srt_vline = [];
            end
            if ~isempty(app.M_In_end_vline)
                delete(app.M_In_end_vline);
                app.M_In_end_vline =[];
            end
            if ~isempty(app.M_In_shadedArea)
                delete(app.M_In_shadedArea);
                app.M_In_shadedArea =[];
            end

            % Output plot reset
            if ~isempty(app.M_Out_srt_vline)
                delete(app.M_Out_srt_vline);
                app.M_Out_srt_vline = [];
            end
            if ~isempty(app.M_Out_end_vline)
                delete(app.M_Out_end_vline);
                app.M_Out_end_vline = [];
            end
            if ~isempty(app.M_Out_shadedArea)
                delete(app.M_Out_shadedArea);
                app.M_Out_shadedArea = [];
            end
        end

        function UL_resetMarks_withoutSliderUpdate(app)
            % Uploader plot reset
            if ~isempty(app.UL_srt_vline)
                delete(app.UL_srt_vline);
                app.UL_srt_vline = [];
            end
            if ~isempty(app.UL_end_vline)
                delete(app.UL_end_vline);
                app.UL_end_vline = [];
            end
            if ~isempty(app.UL_shadedArea)
                delete(app.UL_shadedArea);
                app.UL_shadedArea = [];
            end
        end

        function UL_NumUpdate(app, ts, vs)
            sel_empty=app.isValid_TVpair(ts, vs);

            if ~sel_empty
                duration = ts(end)-ts(1);
                intervals = diff(ts);
                avgInterval = duration/length(ts);

                % Sampling Rate
                app.UL_SamplingRateEditField.Value = duration/length(intervals);

                % Duration
                app.UL_DurationEditField.Value = duration;

                % Amplitude
                app.UL_MinmVEditField.Value = min(vs);
                app.UL_MaxmVEditField.Value = max(vs);
                app.UL_AvgmVEditField.Value = mean(vs);
                app.UL_PPAmpmVEditField.Value = max(vs)-min(vs);

                % Interval
                app.UL_MinmsEditField.Value = min(intervals);
                app.UL_MaxmsEditField.Value = max(intervals);
                app.UL_AvgmsEditField.Value = avgInterval;
                app.UL_StdmsEditField.Value = std(intervals);
                
            else
                tot_empty = app.isValid_TVpair(app.UL_tBuf, app.UL_vBuf);
                if ~tot_empty
                    app.UL_NumUpdate(app.UL_tBuf, app.UL_vBuf)
                else
                    app.UL_ZeroNum();
                end
            end
        end
        
        function UL_ZeroNum(app)
            % Sampling Rate
            app.UL_SamplingRateEditField.Value = 0;

            % Duration
            app.UL_DurationEditField.Value = 0;

            % Amplitude
            app.UL_MinmVEditField.Value = 0;
            app.UL_MaxmVEditField.Value = 0;
            app.UL_AvgmVEditField.Value = 0;
            app.UL_PPAmpmVEditField.Value = 0;

            % Interval
            app.UL_MinmsEditField.Value = 0;
            app.UL_MaxmsEditField.Value = 0;
            app.UL_AvgmsEditField.Value = 0;
            app.UL_StdmsEditField.Value = 0;
        end
        
        function [vis_ts, vis_vins, vis_vouts]= M_plot(app)
            vis_ts=[]; vis_vins = []; vis_vouts=[];
            if ~isempty(app.M_Disp_tBuf)
                rtlim = app.M_Disp_tBuf(end);
                ltlim = rtlim - app.M_tTickNumDisp * app.M_tTickSize;
                if app.M_In_Enable
                    plot(app.M_In_Plot, app.M_Disp_tBuf, app.M_Disp_In_vBuf);
                    xlim(app.M_In_Plot, [ltlim, rtlim]);

                end
                if app.M_Out_Enable
                    plot(app.M_Out_Plot, app.M_Disp_tBuf, app.M_Disp_Out_vBuf);
                    xlim(app.M_Out_Plot, [ltlim, rtlim]);
                end
                
                % Update the plot
                drawnow;
                
                vis_idxs = find(app.M_Disp_tBuf >= ltlim);
                vis_ts = app.M_Disp_tBuf(vis_idxs);
                % Visible ts and vs
                if app.M_In_Enable
                    vis_vins = app.M_Disp_In_vBuf(vis_idxs);
                end
                if app.M_Out_Enable
                    vis_vouts = app.M_Disp_Out_vBuf(vis_idxs);
                end
            end
        end

        function M_NumUpdate(app, ts, in_vs, out_vs) %Update all the EditField in Monitor tab
            if ~isempty(ts)
                duration = ts(end) - ts(1);
                app.M_DurationEditField.Value = duration;
                app.M_FsHzEditField.Value = 1000*(length(ts)-1)/duration;
                if app.M_In_Enable
                    app.M_In_MaxmVEditField.Value=max(in_vs);
                    app.M_In_MinmVEditField.Value=min(in_vs);
                    app.M_In_AvgmVEditField.Value=mean(in_vs);
                    app.M_In_PPAmpmVEditField.Value=max(in_vs)-min(in_vs);
                else
                    app.M_In_MaxmVEditField.Value=0;
                    app.M_In_MinmVEditField.Value=0;
                    app.M_In_AvgmVEditField.Value=0;
                    app.M_In_PPAmpmVEditField.Value=0;
                end
                if app.M_Out_Enable
                    app.M_Out_MaxmVEditField.Value=max(out_vs);
                    app.M_Out_MinmVEditField.Value=min(out_vs);
                    app.M_Out_AvgmVEditField.Value=mean(out_vs);
                    app.M_Out_PPAmpmVEditField.Value=max(out_vs)-min(out_vs);
                else
                    app.M_Out_MaxmVEditField.Value=0;
                    app.M_Out_MinmVEditField.Value=0;
                    app.M_Out_AvgmVEditField.Value=0;
                    app.M_Out_PPAmpmVEditField.Value=0;
                end
            end
            
        end

        function [srt_vline, end_vline, shadedArea,sel_ts, sel_vs]=drawMarks(app,  per1, per2, plot, ts, vs)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % per1, per2 : percentage-represented location of two cursors
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Tmin=plot.XLim(1); Tmax=plot.XLim(2);

            Tcur1=Tmin+(Tmax-Tmin)*per1;
            Tcur2=Tmin+(Tmax-Tmin)*per2;
            
            max = 2^20;
            % Graphic Components Update
            srt_vline = line(plot, [Tcur1, Tcur1], [-max, max], 'Color', 'red', 'LineWidth', 2);
            end_vline = line(plot, [Tcur2, Tcur2], [-max, max], 'Color', 'blue', 'LineWidth', 2);
            shadedArea = patch(plot, ...
                         'XData', [Tcur1, Tcur1, Tcur2, Tcur2], ...
                         'YData', [-max, max, max, -max], ...
                         'FaceColor', 'black', 'FaceAlpha', 0.3);

            % Where it marks
            sel_idxs=find(ts>=Tcur1 & ts<=Tcur2);
            
            sel_ts=[];
            sel_vs=[];

            if ~isempty(sel_idxs)
                sel_ts=ts(sel_idxs);
                sel_vs=vs(sel_idxs);
            end
            return
        end
        %% 
        function datMat=oneTimeRead(app)
            try
                if ~isempty(app.device) && app.device.NumBytesAvailable > 0
                    data =read(app.device,app.device.NumBytesAvailable,"string");
                    dataParts=extractBetween(data,";",";");
                    datMat=double(split(dataParts,","));
                    s=size(datMat);
                    if(s(1)~=3 && s(2)~=3)
                        return
                    elseif (s(1)==3 && s(2)~=3)
                        datMat=datMat';
                    end
                end
            catch
                app.timerSwitch(app.dataTimer, false);
                app.timerSwitch(app.processTimer, false);
                
                app.isRunning = false;
                if app.isRecording
                    app.M_RECORDButtonPushed();
                end
                app.isRecording=false;

                delete(app.device);
                app.device = [];

                %% GUI Update
                app.M_StopGUIEnable();
                app.PortsDropDown.Value = app.default_port_message;
    
                uialert(app.UIFigure, 'Device disconnected.', 'Device Error');
            end
        end

        function M_flushAllBufferInMatlab(app)
            app.M_Disp_In_vBuf=[];app.M_Disp_Out_vBuf=[];app.M_Disp_tBuf=[];
            app.M_Save_In_vBuf=[];app.M_Save_Out_vBuf=[];app.M_Save_tBuf=[];
        end
        
        function empty=isValid_TVpair(app, ts, vs)
            if isempty(ts) && isempty(vs)
                disp('Warning: ts and vs are both emtpy');
                empty = true;
                return;
            elseif ~isempty(ts) && ~isempty(vs)
                if numel(ts)~=numel(vs)
                    error('Error: ts and vs have different length.')
                end
            else
                error('Error: Either ts or ts is empty.')
            end
            empty=false;
        end
           
        function portsUpdate(app)
             availablePorts = serialportlist("available");
            app.PortsDropDown.Items = [app.default_port_message, availablePorts];
        end
        
        function timerSwitch(app, timer, on)
            if ~isempty(timer)
                if on && strcmp(timer.Running, 'off')
                    start(timer);
                end
    
                if ~on && strcmp(timer.Running, 'on')
                    stop(timer);
                end
            else
                disp('Timer is empty');
            end
            
        end
        
        function vs1_mV = resampleData(app, ts_ms, vs_mV)
            % Ensure unique timestamps
            [ts_ms, ia] = unique(ts_ms);
            vs_mV = vs_mV(ia);
            
            % Calculate the total duration of the data
            startTime = ts_ms(1);
            endTime = ts_ms(end);
            
            Ts_ms=app.INTERVAL_MS;
        
            % Create a new time vector with the desired sampling frequency
            ts1_ms = startTime:Ts_ms:endTime;
        
            % Ensure the last element matches the end time
            if ts1_ms(end) ~= endTime
                ts1_ms = [ts1_ms endTime];
            end
        
            % Interpolate the original voltage values at the new timestamps
            vs1_mV = interp1(ts_ms, vs_mV, ts1_ms, 'spline');


        end


        function M_StopGUIEnable(app)
            app.PortsDropDown.Enable = "on";

            % Monitor
            app.M_ModeDropDown.Enable = 'on';

            if app.M_In_Enable
                app.M_In_Slider.Enable="on";
            end
            if app.M_Out_Enable
                app.M_Out_Slider.Enable="on";
            end

            % Uploader
            app.UL_UploadButton.Enable = "on";
            app.UL_FlushButton.Enable = "on";
        end
        
        function M_RunGUIDisable(app)
            app.PortsDropDown.Enable = "off";

            % Monitor
            app.M_ModeDropDown.Enable = 'off';
           
            app.M_resetMarks_withoutSliderUpdate();
            app.M_In_Slider.Value = [0, 100];
            app.M_Out_Slider.Value = [0, 100];


            app.M_In_Slider.Enable="off";
            app.M_Out_Slider.Enable="off";

            % Uploader
            app.UL_UploadButton.Enable ="off";
            app.UL_FlushButton.Enable ="off";

        end
        
        function UL_UploadStartGUIDisable(app)
            app.PortsDropDown.Enable = "off";
            % Uploader tab
            app.UL_UploadButton.Enable = "off";
            app.UL_FlushButton.Enable = "off";
            % Monitor tab
            app.M_RUNSTOPButton.Enable = "off";
            app.M_RECORDButton.Enable = "off";
            app.M_ModeDropDown.Enable = "off";
        end

        function UL_UploadEndGUIEnable(app)
            app.PortsDropDown.Enable = "on";
            % Uploader tab
            app.UL_UploadButton.Enable = "on";
            app.UL_FlushButton.Enable = "on";
            % Monitor tab
            app.M_RUNSTOPButton.Enable = "on";
            app.M_RECORDButton.Enable = "on";
            app.M_ModeDropDown.Enable = "on";
        end
        
        function M_ImpStartGUIDisable(app)
            app.PortsDropDown.Enable ="off";

            % Monitor
            app.M_RUNSTOPButton.Enable = false;
            app.M_SAVEButton.Enable =false;
            app.M_ModeDropDown.Enable = "off";

            % Uploader
            app.UL_UploadButton.Enable = "off";
            app.UL_FlushButton.Enable = "off";
        end
        
        function M_ImpEndGUIEnable(app)
            app.PortsDropDown.Enable ="on";
            
            % Monitor
            app.M_RUNSTOPButton.Enable = true;
            app.M_SAVEButton.Enable =true;
            app.M_ModeDropDown.Enable = "on";

            % Uploader
            app.UL_UploadButton.Enable = "on";
            app.UL_FlushButton.Enable = "on";
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.isRunning=false;
            app.isRecording=false;

            app.portsUpdate();
            app.PortsDropDown.Value = app.default_port_message;
        
            % Initialize timers but don't start them yet
            app.dataTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.3, ...
                                  'TimerFcn', @(src, event)acquireData(app));
            app.processTimer = timer('ExecutionMode', 'fixedRate', 'Period', 2, ...
                                  'TimerFcn', @(src, event)updatePlot(app));
            app.validationTimer = timer('ExecutionMode', 'fixedRate', 'Period', 1, ...
                                  'TimerFcn', @(src, event)validateDevice(app));

            %% Monitor Init
            app.M_tTickSize=str2num(app.M_TimeScaleDropDown.Value);

            % Configuration Init to Record
            app.M_ModeDropDown.Value='Record';
            app.M_In_Enable=true;
            app.M_Out_Enable=false;
            app.M_In_Slider.Enable=true;
            app.M_Out_Slider.Enable=false;

            % Monitor Plot Init
            app.M_In_Plot.XLimMode = 'manual';
            app.M_Out_Plot.XLimMode = 'manual';
            app.M_In_Plot.YLimMode="auto";
            app.M_Out_Plot.YLimMode="auto";
            
            %% Uploader Init
            % Uploader Plot Init
            app.UL_Plot.XLimMode = 'auto';
            app.UL_Plot.YLimMode = "auto";

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            if ~isempty(app.device)
                app.stateChangeNotify(app.SIG_IDLE);
                app.stateChangeNotify(app.SIG_FLUSH);
            end
            if ~isempty(app.dataTimer)
                stop(app.dataTimer);
                app.timerSwitch(app.dataTimer,false);
                delete(app.dataTimer);
            end
            if ~isempty(app.processTimer)
                app.timerSwitch(app.processTimer,false);
                delete(app.processTimer);
            end
            if ~isempty(app.device)
                delete(app.device);
            end
            delete(app.UIFigure);

        end

        % Value changed function: PortsDropDown
        function PortsDropDownValueChanged(app, event)
            
            value = app.PortsDropDown.Value;
            if ~strcmp(value, app.default_port_message)
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                try
                    app.timerSwitch(app.dataTimer, false);
                    app.timerSwitch(app.processTimer, false);
                    delete(app.device);
                    app.device = [];
                    
                    % isRecording, isRunning
                    if app.isRecording
                        app.M_RECORDButton();
                    end
                    app.isRecording=false;
                    app.isRunning=false;
                    
                    % Buffer
                    app.M_Disp_In_vBuf=[];app.M_Disp_Out_vBuf=[];app.M_Disp_tBuf=[];
                    app.M_Save_In_vBuf=[];app.M_Save_Out_vBuf=[];app.M_Save_tBuf=[];
                    
                    % Plot
                    cla(app.M_In_Plot);
                    cla(app.M_Out_Plot);
                    
                    % Set app.device
                    app.device = serialport(value, 9600);
                    configureTerminator(app.device, "CR/LF");
                    start(app.validationTimer);

                    % Seeeduino state init
                    app.stateChangeNotify(app.SIG_IDLE);
                catch exception
                    uialert(app.UIFigure, exception.message, 'Serial Port Error');
                    app.PortsDropDown.Value = app.default_port_message;
                end
            else
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
            end

        end

        % Button pushed function: M_RUNSTOPButton
        function M_RUNSTOPButtonPushed(app, event)
            %% Validation
            if isempty(app.device)
                uialert(app.UIFigure, 'Select the port before running.', 'Serial Port Error');
                return
            end
            
            if ~app.isIRmode 
            %% Record, Playback and Record & Playback
                if app.isRunning % Stop
                    if ~app.isRecording
                        app.timerSwitch(app.dataTimer,false);
                        app.timerSwitch(app.processTimer, false);
                    end
                    
                    % GUI Enable
                    app.M_In_Plot.YLimMode='manual';
                    app.M_Out_Plot.YLimMode = 'manual';
                    app.M_StopGUIEnable();
                    
                    % Turn to IDLE
                    app.stateChangeNotify(app.SIG_IDLE);
                    app.timerSwitch(app.validationTimer, true);

                    app.isRunning = false;
                else % Run
                    app.UL_ZeroNum();

                    % Turn to MONITOR
                    app.timerSwitch(app.validationTimer, false);
                    app.stateChangeNotify(app.SIG_MONITOR);
                    app.timerSwitch(app.dataTimer, true);
                    app.timerSwitch(app.processTimer, true);
                    
                    % GUI
                    app.M_In_Plot.YLimMode='auto';
                    app.M_Out_Plot.YLimMode = 'auto';
                    app.M_RunGUIDisable();

                    app.isRunning = true;
                end
            else 
            %% Impulse Response Mode
                app.timerSwitch(app.validationTimer, false);

                app.M_Disp_tBuf = [];
                app.M_Disp_In_vBuf = [];
                app.M_Disp_Out_vBuf =[];
                
                %% GUI
                app.M_In_Plot.YLimMode='auto';
                app.M_Out_Plot.YLimMode = 'auto';

                app.M_ImpStartGUIDisable();
                
                try
                    app.stateChangeNotify(app.SIG_IMPULSE);
    
                    while(app.device.NumBytesAvailable==0) % Wait for processing
                        pause(0.01);
                    end
    
                    while(app.device.NumBytesAvailable>0) % Seeeduino starts to send the data
                        datMat = app.oneTimeRead();
                        if ~isempty(datMat)
                            ts = datMat(:,1); vins=datMat(:,2); vouts=datMat(:,3);
                            app.M_Disp_tBuf = [app.M_Disp_tBuf ; ts];
                            app.M_Disp_In_vBuf = [app.M_Disp_In_vBuf ; vins];
                            app.M_Disp_Out_vBuf = [app.M_Disp_Out_vBuf; vouts];
                        end
                        pause(0.1); % Prevent read too fast.
                    end
                catch
                    app.M_ImpEndGUIEnable();
                    app.timerSwitch(app.validationTimer, true);
                end

                % Manually change the time scale to make it look good
                app.M_TimeScaleDropDown.Value = '100';
                app.M_tTickSize = 100;
                
                
                app.M_plot();
                app.M_NumUpdate(app.M_Disp_tBuf, app.M_Disp_In_vBuf, app.M_Disp_Out_vBuf);

                app.M_In_Plot.YLimMode='manual';
                app.M_Out_Plot.YLimMode = 'manual';

                
                % Enables again
                app.M_ImpEndGUIEnable();
                

                app.timerSwitch(app.validationTimer, true);
            end
        end

        % Button pushed function: M_SAVEButton
        function M_SAVEButtonPushed(app, event)
            idxs=[];
            ts=[]; vins=[]; vouts=[];

            if app.M_In_Enable && ~isempty(app.M_In_shadedArea)
                tVertices = app.M_In_shadedArea.XData;
            elseif app.M_Out_Enable && ~isempty(app.M_Out_shadedArea)
                tVertices = app.M_Out_shadedArea.XData;
            else
                uialert(app.UIFigure, 'No selection on the plot.', 'Save Error');
                return
            end
            
            if isempty(tVertices)
                error('We have shadedArea but no vertices?')
                return
            end
            srt_t = min(tVertices);end_t = max(tVertices);
            idxs = find(app.M_Disp_tBuf >= srt_t & app.M_Disp_tBuf <= end_t);

            if ~isempty(idxs)
                ts = app.M_Disp_tBuf(idxs);
                if app.M_In_Enable
                    vins=app.M_Disp_In_vBuf(idxs);
                end
                if app.M_Out_Enable
                    vouts = app.M_Disp_Out_vBuf(idxs);
                end
                
            end
            if isempty(vins) && isempty(vouts)
                uialert(app.UIFigure, 'No data falls within the selected range.', 'Save Error');
                return
            end
            app.saveCSV(ts, vins, vouts);
        end

        % Button pushed function: M_RECORDButton
        function M_RECORDButtonPushed(app, event)
            

            if app.isRecording % Stop recording and save the data
                if ~app.isRunning && strcmp(app.dataTimer.Running, 'on')
                    stop(app.dataTimer);
                end
                app.saveCSV(app.M_Save_tBuf, app.M_Save_In_vBuf, app.M_Save_Out_vBuf);
                app.M_RecordLamp.Color=[0.80,0.80,0.80];
                

                % Free the save buffers
                app.M_Save_tBuf = [];app.M_Save_In_vBuf=[];app.M_Save_Out_vBuf=[];

                app.isRecording=false;

                % Uploader
                if ~app.isRunning
                    app.UL_UploadButton.Enable = "on";
                    app.UL_FlushButton.Enable = "on";
                end
            else % Start recording
                % Port should be selected first
                if isempty(app.device)
                    uialert(app.UIFigure, 'Select the port before running.', 'Serial Port Error');
                    return
                end

                app.M_RecordLamp.Color='red';
                if strcmp(app.dataTimer.Running, 'off')
                    start(app.dataTimer);
                end
                app.isRecording =true;

                % Uploader
                app.UL_UploadButton.Enable = "off";
                app.UL_FlushButton.Enable = "off";
            end
            
        end

        % Button pushed function: UL_SearchButton
        function UL_SearchButtonPushed(app, event)
            % Open a file open dialog allowing the user to specify a CSV file
                [fileName, path] = uigetfile({'*.csv','CSV Files (*.csv)'}, 'Select the file.');
                if isequal(fileName, 0) || isequal(path, 0)
                    disp('User clicked Cancel.');
                else
                    % Construct the full path where the file will be saved
                    fullPath = fullfile(path, fileName);
                    disp(['User selected ', fullPath, ' for playing.']);
                    
                    % Read the data from the CSV file
                    [app.UL_tBuf, app.UL_vBuf] = app.readCSV(fullPath);
            
                    % Normalize the time buffer to start from zero
                    app.UL_tBuf = app.UL_tBuf - min(app.UL_tBuf);                
                    
                    app.UL_Plot.XLimMode='auto';
                    app.UL_Plot.YLimMode='auto';

                    % Plot the data
                    plot(app.UL_Plot, app.UL_tBuf, app.UL_vBuf);
                    
                    % By the unknown reason, this doesn't work
                    % app.UL_Plot.XLimMode='manual';
                    % app.UL_Plot.YLimMode='manual';

                    % EditField Update
                    app.UL_NumUpdate(app.UL_tBuf, app.UL_vBuf);
                    
                    % Update the file edit field with the selected file name
                    app.UL_FileEditField.Value = fileName;

                end
        end

        % Value changed function: UL_Slider
        function UL_SliderValueChanged(app, event)
            app.UL_Plot.XLimMode='manual';
            app.UL_Plot.YLimMode='manual';

            value = app.UL_Slider.Value;
            per1 = value(1)/100;
            per2 = value(2)/100;
            
            %% Update Marks & EditVields
            app.UL_resetMarks_withoutSliderUpdate();
            [app.UL_srt_vline, app.UL_end_vline, app.UL_shadedArea, sel_ts, sel_vs]=app.drawMarks(per1, per2, app.UL_Plot, app.UL_tBuf, app.UL_vBuf);
            app.UL_NumUpdate(sel_ts, sel_vs);
        end

        % Button pushed function: UL_FlushButton
        function UL_FlushButtonPushed(app, event)
            % Port should be selected first
            if isempty(app.device)
                uialert(app.UIFigure, 'Select the port before running.', 'Serial Port Error');
                return
            end

            app.stateChangeNotify(app.SIG_FLUSH);
            pause(1);
        end

        % Button pushed function: UL_UploadButton
        function UL_UploadButtonPushed(app, event)
            %% Validation 
            % Port should be selected before uploading.
            if isempty(app.device)
                uialert(app.UIFigure, 'Select the port before running.', 'Serial Port Error');
                return
            end
            % Data should be selected before uploading.
            if isempty(app.UL_tBuf)
                uialert(app.UIFigure, 'No data available to save.', 'Save Error');
                return
            end
            
            %% Turn to UPL
            app.timerSwitch(app.validationTimer, false);

            %% GUI Disable
            app.UL_UploadStartGUIDisable();

            
            ts_ms=[];vs_mV=[];
            if ~isempty(app.UL_shadedArea)
                tVertices = app.UL_shadedArea.XData;
                srt_t = min(tVertices);
                end_t = max(tVertices);
                sel_idxs = find(app.UL_tBuf>=srt_t & app.UL_tBuf<=end_t);
                if ~isempty(sel_idxs)
                    ts_ms = app.UL_tBuf(sel_idxs);
                    vs_mV = app.UL_vBuf(sel_idxs);
                end
            else
                ts_ms = app.UL_tBuf;
                vs_mV = app.UL_vBuf;
            end
            
            if(ts_ms(end)-ts_ms(1)>app.INTERVAL_MS * app.MAX_NUM_SAMPLE)
                uialert(app.UIFigure, 'Too many sample.', 'Length limitaion')
            end

            vs1_uint16...
                =uint16(app.resampleData(ts_ms, vs_mV)/app.MAX_V_mV*app.MAX_OUT_V_NUM_OF_BIN);

            %% Uploading
            try
                if ~isempty(app.device)
                    % State Change to UPL
                    app.stateChangeNotify(app.SIG_FLUSH);
                    pause(0.5);
                    app.stateChangeNotify(app.SIG_UPLOAD);
                    pause(0.5);
                    
                    % Uploading
                    len=length(vs1_uint16);
                    for i = 1:len
                        write(app.device, vs1_uint16(i), 'uint16');
                        pause(0.01);
                    end
                    
                    % State Change to IDLE
                    app.stateChangeNotify(app.SIG_IDLE);
                end
            catch
                delete(app.device);
                app.device = [];

                %% GUI Update
                app.UL_UploadEndGUIEnable();
                app.PortsDropDown.Value = app.default_port_message;
    
                uialert(app.UIFigure, 'Device disconnected.', 'Device Error');
            end

            %% Turn to IDLE
            app.timerSwitch(app.validationTimer, true);

            %% GUI enable
            app.UL_UploadEndGUIEnable();
            
        end

        % Value changed function: M_Out_Slider
        function M_Out_SliderValueChanged(app, event)
            value = app.M_Out_Slider.Value;
            per1 = value(1)/100;
            per2 = value(2)/100;
            
            app.M_resetMarks_withoutSliderUpdate();
            M_sel_ts=[];
            [app.M_Out_srt_vline, app.M_Out_end_vline, app.M_Out_shadedArea, M_sel_ts, M_Out_sel_vs]=...
                app.drawMarks(per1, per2, app.M_Out_Plot, app.M_Disp_tBuf, app.M_Disp_Out_vBuf);

            
            M_In_sel_vs=[];
            if app.M_In_Enable
                app.M_In_Slider.Value=value;
                xlim(app.M_In_Plot, app.M_Out_Plot.XLim);
                [app.M_In_srt_vline, app.M_In_end_vline, app.M_In_shadedArea, ~, M_In_sel_vs]=...
                    app.drawMarks(per1, per2, app.M_In_Plot, app.M_Disp_tBuf, app.M_Disp_In_vBuf);
            end
            
            app.M_NumUpdate(M_sel_ts, M_In_sel_vs, M_Out_sel_vs);
        end

        % Button pushed function: UL_ClearButton
        function UL_ClearButtonPushed(app, event)
            app.UL_resetMarks_withoutSliderUpdate();

            vis_idxs = [];
            ltlim = app.UL_Plot.XLim(1); rtlim = app.UL_Plot.XLim(2);
            vis_idxs = find(app.UL_tBuf>=ltlim & app.UL_tBuf<=rtlim);
            
            vis_ts = []; vis_vs =[];
            if ~isempty(vis_idxs)
                vis_ts = app.UL_tBuf(vis_idxs); vis_vs = app.UL_vBuf(vis_idxs);
            end

            app.UL_NumUpdate(vis_ts, vis_vs);
        end

        % Value changed function: M_ModeDropDown
        function M_ModeDropDownValueChanged(app, event)
            %% End up the last state
            % app.isRunning=false is assumed.
            if app.isRecording
                app.M_RECORDButtonPushed();
            end
            app.isRecording = false;
            
            %%
            value = app.M_ModeDropDown.Value;
            
            cla(app.M_Out_Plot);
            cla(app.M_In_Plot);
            
            app.M_In_Slider.Value = [0, 100];
            app.M_Out_Slider.Value = [0, 100];

            app.M_Disp_tBuf = [];
            app.M_Disp_In_vBuf = [];
            app.M_Disp_Out_vBuf = [];

            app.UL_ZeroNum();

            if ~strcmp(value, 'Impulse Response')
                
                app.M_RECORDButton.Enable ="on";
                app.isIRmode = false;
                if strcmp(value, 'Record')
                    app.M_In_Enable = true;
                    app.M_Out_Enable = false;
                    app.M_In_Slider.Enable="on";
                    app.M_Out_Slider.Enable="off";
                elseif strcmp(value, 'Playback')
                    app.M_In_Enable = false;
                    app.M_Out_Enable = true;
                    app.M_In_Slider.Enable="off";
                    app.M_Out_Slider.Enable="on";
                elseif strcmp(value, 'Record & Playback')
                    app.M_In_Enable = true;
                    app.M_Out_Enable = true;
                    app.M_In_Slider.Enable="on";
                    app.M_Out_Slider.Enable="on";
                end
            else 
                app.M_In_Enable = true;
                app.M_Out_Enable = true;
                app.M_In_Slider.Enable="on";
                app.M_Out_Slider.Enable="on";

                app.M_RECORDButton.Enable ="off";
                app.isIRmode = true;
            end


            app.M_flushAllBufferInMatlab();
            
        end

        % Value changed function: M_In_Slider
        function M_In_SliderValueChanged(app, event)
            value = app.M_In_Slider.Value;
            per1 = value(1)/100;
            per2 = value(2)/100;
            
            app.M_resetMarks_withoutSliderUpdate();
            
            [app.M_In_srt_vline, app.M_In_end_vline, app.M_In_shadedArea, M_sel_ts, M_In_sel_vs]=...
                app.drawMarks(per1, per2, app.M_In_Plot, app.M_Disp_tBuf, app.M_Disp_In_vBuf);
 

            M_Out_sel_vs=[];
            if app.M_Out_Enable
                app.M_Out_Slider.Value=value;
                xlim(app.M_Out_Plot, app.M_In_Plot.XLim);
                [app.M_Out_srt_vline, app.M_Out_end_vline, app.M_Out_shadedArea, ~, M_Out_sel_vs]=...
                    app.drawMarks(per1, per2, app.M_Out_Plot, app.M_Disp_tBuf, app.M_Disp_Out_vBuf);
            end


            app.M_NumUpdate(M_sel_ts, M_In_sel_vs, M_Out_sel_vs);
        end

        % Value changed function: M_TimeScaleDropDown
        function M_TimeScaleDropDownValueChanged(app, event)
            value = app.M_TimeScaleDropDown.Value;
            app.M_tTickSize = str2num(value);
            
            app.M_plot();
        end

        % Button pushed function: M_CLEARButton
        function M_CLEARButtonPushed(app, event)
            app.M_resetMarks_withoutSliderUpdate();
            app.M_In_Slider.Value = [0, 100];
            app.M_Out_Slider.Value = [0, 100];
            
            vis_idxs = []; ltlim = 0; rtlim =0;
            if app.M_In_Enable
                ltlim = app.M_In_Plot.XLim(1); rtlim = app.M_In_Plot.XLim(2);
            elseif app.M_Out_Enable
                ltlim = app.M_Out_Plot.XLim(1); rtlim = app.M_Out_Plot.XLim(2);
            end
            vis_idxs = find(app.M_Disp_tBuf>=ltlim & app.M_Disp_tBuf<=rtlim);
            
            vis_ts = []; vis_vins =[]; vis_vouts =[];
            if ~isempty(vis_idxs)
                vis_ts = app.M_Disp_tBuf(vis_idxs); 
                if app.M_In_Enable
                    vis_vins = app.M_Disp_In_vBuf(vis_idxs); 
                elseif app.M_Out_Enable
                    vis_vouts = app.M_Disp_Out_vBuf(vis_idxs); 
                end

            end

            app.M_NumUpdate(vis_ts, vis_vins, vis_vouts);

        end

        % Drop down opening function: PortsDropDown
        function PortsDropDownOpening(app, event)
            availablePorts = serialportlist("available");
            app.PortsDropDown.Items = [app.default_port_message, availablePorts];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MonitorTab = uitab(app.TabGroup);
            app.MonitorTab.Title = 'Monitor';
            app.M_In_Plot = uiaxes(app.MonitorTab);
            zlabel(app.M_In_Plot, 'Z')
            app.M_In_Plot.XTickLabel = '';
            app.M_Out_Plot = uiaxes(app.MonitorTab);
            zlabel(app.M_Out_Plot, 'Z')
            app.M_Out_Plot.XTickLabel = '';
            app.M_RecordLamp = uilamp(app.MonitorTab);
            app.InputLabel = uilabel(app.MonitorTab);
            app.InputLabel.Text = 'Input';
            app.M_In_PPAmpmVEditField = uieditfield(app.MonitorTab, 'numeric');
            app.M_In_PPAmpmVEditField.Editable = 'off';
            app.M_In_MinmVEditField = uieditfield(app.MonitorTab, 'numeric');
            app.M_In_MinmVEditField.Editable = 'off';
            app.M_In_MaxmVEditField = uieditfield(app.MonitorTab, 'numeric');
            app.M_In_MaxmVEditField.Editable = 'off';
            app.M_In_AvgmVEditField = uieditfield(app.MonitorTab, 'numeric');
            app.M_In_AvgmVEditField.Editable = 'off';
            app.MaxmVLabel_2 = uilabel(app.MonitorTab);
            app.MaxmVLabel_2.Text = 'Max[mV]';
            app.MinmVLabel_2 = uilabel(app.MonitorTab);
            app.MinmVLabel_2.Text = 'Min[mV]';
            app.AvgmVLabel_2 = uilabel(app.MonitorTab);
            app.AvgmVLabel_2.Text = 'Avg[mV]';
            app.PPmVLabel_2 = uilabel(app.MonitorTab);
            app.PPmVLabel_2.Text = 'P-P[mV]';
            app.OutputLabel = uilabel(app.MonitorTab);
            app.OutputLabel.Text = 'Output';
            app.M_Out_PPAmpmVEditField = uieditfield(app.MonitorTab, 'numeric');
            app.M_Out_PPAmpmVEditField.Editable = 'off';
            app.M_Out_MinmVEditField = uieditfield(app.MonitorTab, 'numeric');
            app.M_Out_MinmVEditField.Editable = 'off';
            app.M_Out_MaxmVEditField = uieditfield(app.MonitorTab, 'numeric');
            app.M_Out_MaxmVEditField.Editable = 'off';
            app.M_Out_AvgmVEditField = uieditfield(app.MonitorTab, 'numeric');
            app.M_Out_AvgmVEditField.Editable = 'off';
            app.MaxmVLabel_3 = uilabel(app.MonitorTab);
            app.MaxmVLabel_3.Text = 'Max[mV]';
            app.MinmVLabel_3 = uilabel(app.MonitorTab);
            app.MinmVLabel_3.Text = 'Min[mV]';
            app.AvgmVLabel_3 = uilabel(app.MonitorTab);
            app.AvgmVLabel_3.Text = 'Avg[mV]';
            app.PPmVLabel_3 = uilabel(app.MonitorTab);
            app.PPmVLabel_3.Text = 'P-P[mV]';
            app.M_Out_Slider = uislider(app.MonitorTab, 'range');
            app.M_Out_Slider.MajorTicks = [0 20 40 60 80 100];
            app.M_Out_Slider.MajorTickLabels = {''};
            app.M_Out_Slider.ValueChangedFcn = createCallbackFcn(app, @M_Out_SliderValueChanged, true);
            app.M_In_Slider = uislider(app.MonitorTab, 'range');
            app.M_In_Slider.MajorTicks = [0 20 40 60 80 100];
            app.M_In_Slider.MajorTickLabels = {''};
            app.M_In_Slider.ValueChangedFcn = createCallbackFcn(app, @M_In_SliderValueChanged, true);
            app.M_RUNSTOPButton = uibutton(app.MonitorTab, 'push');
            app.M_RUNSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @M_RUNSTOPButtonPushed, true);
            app.M_RUNSTOPButton.Text = 'RUN/STOP';
            app.M_SAVEButton = uibutton(app.MonitorTab, 'push');
            app.M_SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @M_SAVEButtonPushed, true);
            app.M_SAVEButton.Text = 'SAVE';
            app.M_RECORDButton = uibutton(app.MonitorTab, 'push');
            app.M_RECORDButton.ButtonPushedFcn = createCallbackFcn(app, @M_RECORDButtonPushed, true);
            app.M_RECORDButton.Text = 'RECORD';
            app.M_CLEARButton = uibutton(app.MonitorTab, 'push');
            app.M_CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @M_CLEARButtonPushed, true);
            app.M_CLEARButton.Text = 'CLEAR';
            app.M_TimeScaleDropDown = uidropdown(app.MonitorTab);
            app.M_TimeScaleDropDown.Items = {'10ms', '50ms', '100ms', '500ms', '1s', '2s', '5s', '10s', '30s', '1min'};
            app.M_TimeScaleDropDown.ItemsData = {'1', '5', '10', '50', '100', '200', '500', '1000', '3000', '6000'};
            app.M_TimeScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @M_TimeScaleDropDownValueChanged, true);
            app.M_TimeScaleDropDown.Value = '1';
            app.M_ModeDropDown = uidropdown(app.MonitorTab);
            app.M_ModeDropDown.Items = {'Record', 'Playback', 'Record & Playback', 'Impulse Response'};
            app.M_ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @M_ModeDropDownValueChanged, true);
            app.M_ModeDropDown.Value = 'Record';
            app.M_DurationEditField = uieditfield(app.MonitorTab, 'numeric');
            app.M_FsHzEditField = uieditfield(app.MonitorTab, 'numeric');
            app.mVLabel_2 = uilabel(app.MonitorTab);
            app.mVLabel_2.Text = '[mV]';
            app.mVLabel_3 = uilabel(app.MonitorTab);
            app.mVLabel_3.Text = '[mV]';
            app.TimeScaleLabel = uilabel(app.MonitorTab);
            app.TimeScaleLabel.Text = 'Time Scale';
            app.FsHzLabel_2 = uilabel(app.MonitorTab);
            app.FsHzLabel_2.Text = 'Fs[Hz]';
            app.DurationmsLabel = uilabel(app.MonitorTab);
            app.DurationmsLabel.Text = 'Duration[ms]';
            app.ModeLabel = uilabel(app.MonitorTab);
            app.ModeLabel.Text = 'Mode';
            app.UploaderTab = uitab(app.TabGroup);
            app.UploaderTab.Title = 'Uploader';
            app.UL_Plot = uiaxes(app.UploaderTab);
            zlabel(app.UL_Plot, 'Z')
            app.FileEditFieldLabel = uilabel(app.UploaderTab);
            app.FileEditFieldLabel.Text = 'File';
            app.UL_FileEditField = uieditfield(app.UploaderTab, 'text');
            app.UL_FileEditField.Editable = 'off';
            app.UL_UploadButton = uibutton(app.UploaderTab, 'push');
            app.UL_UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UL_UploadButtonPushed, true);
            app.UL_UploadButton.Text = 'Upload';
            app.UL_SearchButton = uibutton(app.UploaderTab, 'push');
            app.UL_SearchButton.ButtonPushedFcn = createCallbackFcn(app, @UL_SearchButtonPushed, true);
            app.UL_SearchButton.Text = 'Search';
            app.UL_FlushButton = uibutton(app.UploaderTab, 'push');
            app.UL_FlushButton.ButtonPushedFcn = createCallbackFcn(app, @UL_FlushButtonPushed, true);
            app.UL_FlushButton.Text = 'Flush';
            app.UL_DurationEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_DurationEditField.Editable = 'off';
            app.tmsLabel_2 = uilabel(app.UploaderTab);
            app.tmsLabel_2.Text = 'Δt[ms]';
            app.SamplingRateLabel = uilabel(app.UploaderTab);
            app.SamplingRateLabel.Text = 'Sampling Rate';
            app.UL_SamplingRateEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_SamplingRateEditField.Editable = 'off';
            app.FsHzLabel = uilabel(app.UploaderTab);
            app.FsHzLabel.Text = 'Fs[Hz]';
            app.DurationLabel = uilabel(app.UploaderTab);
            app.DurationLabel.Text = 'Duration';
            app.AmplitudeLabel = uilabel(app.UploaderTab);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.UL_PPAmpmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_PPAmpmVEditField.Editable = 'off';
            app.UL_MinmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_MinmVEditField.Editable = 'off';
            app.UL_MaxmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_MaxmVEditField.Editable = 'off';
            app.UL_AvgmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_AvgmVEditField.Editable = 'off';
            app.MaxmVLabel = uilabel(app.UploaderTab);
            app.MaxmVLabel.Text = 'Max[mV]';
            app.MinmVLabel = uilabel(app.UploaderTab);
            app.MinmVLabel.Text = 'Min[mV]';
            app.AvgmVLabel = uilabel(app.UploaderTab);
            app.AvgmVLabel.Text = 'Avg[mV]';
            app.PPmVLabel = uilabel(app.UploaderTab);
            app.PPmVLabel.Text = 'P-P[mV]';
            app.IntervalLabel = uilabel(app.UploaderTab);
            app.IntervalLabel.Text = 'Interval';
            app.UL_MinmsEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_MinmsEditField.Editable = 'off';
            app.UL_StdmsEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_StdmsEditField.Editable = 'off';
            app.UL_AvgmsEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_AvgmsEditField.Editable = 'off';
            app.UL_MaxmsEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_MaxmsEditField.Editable = 'off';
            app.MaxmsLabel = uilabel(app.UploaderTab);
            app.MaxmsLabel.Text = 'Max[ms]';
            app.MinmsLabel = uilabel(app.UploaderTab);
            app.MinmsLabel.Text = 'Min[ms]';
            app.AvgmsLabel = uilabel(app.UploaderTab);
            app.AvgmsLabel.Text = 'Avg[ms]';
            app.StdmsLabel = uilabel(app.UploaderTab);
            app.StdmsLabel.Text = 'Std[ms]';
            app.UL_Slider = uislider(app.UploaderTab, 'range');
            app.UL_Slider.MajorTicks = [0 20 40 60 80 100];
            app.UL_Slider.MajorTickLabels = {''};
            app.UL_Slider.ValueChangedFcn = createCallbackFcn(app, @UL_SliderValueChanged, true);
            app.UL_Slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.UL_ClearButton = uibutton(app.UploaderTab, 'push');
            app.UL_ClearButton.ButtonPushedFcn = createCallbackFcn(app, @UL_ClearButtonPushed, true);
            app.UL_ClearButton.Text = 'Clear';
            app.msLabel = uilabel(app.UploaderTab);
            app.msLabel.Text = '[ms]';
            app.mVLabel = uilabel(app.UploaderTab);
            app.mVLabel.Text = '[mV]';
            app.PortsDropDown = uidropdown(app.UIFigure);
            app.PortsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortsDropDownOpening, true);
            app.PortsDropDown.ValueChangedFcn = createCallbackFcn(app, @PortsDropDownValueChanged, true);
            app.PortLabel = uilabel(app.UIFigure);
            app.PortLabel.Text = 'Port';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hashirama

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end