classdef start_win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ModeDropDown                 matlab.ui.control.DropDown
        ModeDropDownLabel            matlab.ui.control.Label
        AvailablePortsDropDown       matlab.ui.control.DropDown
        AvailablePortsDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Get the list of available serial ports
            availablePorts = serialportlist("available");
        
            % Check if there are any available ports
            if isempty(availablePorts)
                availablePorts = {'No Ports Available'};
            end
            
            % Set the dropdown items
            app.AvailablePortsDropDown.Items = availablePorts;
            
            % Optionally, set the first item as the selected item if ports are available
            if ~strcmp(availablePorts{1}, 'No Ports Available')
                app.AvailablePortsDropDown.Value = availablePorts{1};
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AvailablePortsDropDownLabel = uilabel(app.UIFigure);
            app.AvailablePortsDropDownLabel.Text = 'Available Ports';
            app.AvailablePortsDropDown = uidropdown(app.UIFigure);
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'Record,', 'Playback,', 'Impulse Response Measurement,', 'Zeroing'};
            app.ModeDropDown.Value = 'Record,';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = start_win

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end