classdef unified_win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        RecordTab             matlab.ui.container.Tab
        SAVEButton            matlab.ui.control.Button
        PlotPanel             matlab.ui.container.Panel
        PPAmpVEditField       matlab.ui.control.NumericEditField
        PPAmpVEditFieldLabel  matlab.ui.control.Label
        FreqHzEditField       matlab.ui.control.NumericEditField
        FreqHzEditFieldLabel  matlab.ui.control.Label
        MaxVEditField         matlab.ui.control.NumericEditField
        MaxVEditFieldLabel    matlab.ui.control.Label
        MinVEditField         matlab.ui.control.NumericEditField
        MinVEditFieldLabel    matlab.ui.control.Label
        RecordPlot            matlab.ui.control.UIAxes
        TIMEDIVKnob           matlab.ui.control.DiscreteKnob
        TIMEDIVKnobLabel      matlab.ui.control.Label
        VOLTSDIVKnob          matlab.ui.control.DiscreteKnob
        VOLTSDIVKnobLabel     matlab.ui.control.Label
        RECORDButton          matlab.ui.control.Button
        AUTOSETButton         matlab.ui.control.Button
        RUNSTOPButton         matlab.ui.control.Button
        PlaybackTab           matlab.ui.container.Tab
        Lamp                  matlab.ui.control.Lamp
        ZeroingButton         matlab.ui.control.Button
        EditField             matlab.ui.control.NumericEditField
        Slider                matlab.ui.control.Slider
        LoopSwitch            matlab.ui.control.Switch
        LoopSwitchLabel       matlab.ui.control.Label
        PauseButton           matlab.ui.control.Button
        PlayButton            matlab.ui.control.Button
        PlaybackPlot          matlab.ui.control.UIAxes
        PortsDropDown         matlab.ui.control.DropDown
        PortsDropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        device % The Seeeduino device

        default_port_message='Select a port'
        
        dataBuffer = [] % Buffer for storing data values
        timestampBuffer = [] % Buffer for storing timestamp values
        
        dataTimer % Timer for data acquisition
        plotTimer % Timer for updating the plot
        startTime % Start time of the acquisition for relative timestamps
        showLength = 100 % Number of points to show on the plot

        % RUN/STOP Btn Click
        isRunning logical = true % Flag to updating the plot
        
        % Click management on the plot
        record_plot_clicked logical = false % To track the number of clicks
        vLine1 % To store the handle to the first vertical line
        vLine2 % To store the handle to the second vertical line
        shadedArea % To store the handle to the shaded area patch


    end
    
    methods (Access = private)
        
        function acquireData(app)
            if ~isempty(app.device)
                while app.device.NumBytesAvailable > 0
                    dataLine = readline(app.device);
                    dataParts = strsplit(dataLine, ',');
                    if length(dataParts) == 2
                        millis = str2double(dataParts{1});
                        numericValue = str2double(dataParts{2});
                        if ~isnan(numericValue) && ~isnan(millis)
                            elapsedSeconds = millis / 1000;
                            app.dataBuffer = [app.dataBuffer, numericValue];
                            app.timestampBuffer = [app.timestampBuffer, elapsedSeconds];
                            
                            if length(app.dataBuffer) > app.showLength
                                app.dataBuffer = app.dataBuffer(end-app.showLength+1:end);
                                app.timestampBuffer = app.timestampBuffer(end-app.showLength+1:end);
                            end
                        end
                    end
                end
            end
        end
        
        function updatePlot(app)
            if(app.isRunning)
                plot(app.RecordPlot, app.timestampBuffer, app.dataBuffer);
                drawnow;
            end
        end

        
        function resetMarkOnRecordPlot(app)
            if isgraphics(app.vLine1, 'line')
                delete(app.vLine1);
            end
            if isgraphics(app.vLine2, 'line')
                delete(app.vLine2);
            end
            if isgraphics(app.shadedArea, 'patch')
                delete(app.shadedArea);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            availablePorts = serialportlist("available");
            app.PortsDropDown.Items = [app.default_port_message, availablePorts];
            app.PortsDropDown.Value = app.default_port_message;
        
            % Initialize timers but don't start them yet
            app.dataTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, ...
                                  'TimerFcn', @(src, event)acquireData(app));
            app.plotTimer = timer('ExecutionMode', 'fixedRate', 'Period', 1, ...
                                  'TimerFcn', @(src, event)updatePlot(app));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          
            if ~isempty(app.dataTimer)
                stop(app.dataTimer);
                delete(app.dataTimer);
            end
            if ~isempty(app.plotTimer)
                stop(app.plotTimer);
                delete(app.plotTimer);
            end
            if ~isempty(app.device)
                delete(app.device);
            end
            delete(app.UIFigure);

        end

        % Value changed function: PortsDropDown
        function PortsDropDownValueChanged(app, event)

            value = app.PortsDropDown.Value;
            if ~strcmp(value, app.default_port_message)
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                try
                    app.device = serialport(value, 9600);
                    configureTerminator(app.device, "CR/LF");
                    % Start timers when a valid port is selected and device is configured
                    start(app.dataTimer);
                    start(app.plotTimer);
                catch exception
                    uialert(app.UIFigure, exception.message, 'Serial Port Error');
                    app.PortsDropDown.Value = app.default_port_message;
                end
            else
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                % Stop timers if the default message is selected
                stop(app.dataTimer);
                stop(app.plotTimer);
            end

        end

        % Button down function: RecordPlot
        function RecordPlotButtonDown(app, event)
            % Get the current point clicked by the user
            currentPoint = app.RecordPlot.CurrentPoint;
            xClick = currentPoint(1,1); % X-coordinate of the click
            
            if ~app.isRunning % Can mark only when the plot updating pauses
                if ~app.record_plot_clicked % First click
                    app.resetMarkOnRecordPlot();
                    app.vLine1 = line(app.RecordPlot, [xClick xClick], ...
                                      app.RecordPlot.YLim, 'Color', 'r', 'LineWidth', 2);
                    app.record_plot_clicked = true;
                else % Second Click
                    if isgraphics(app.vLine1, 'line')
                        pastX = app.vLine1.XData(1);
                        if xClick > pastX
                            app.vLine2 = line(app.RecordPlot, [xClick xClick], ...
                                              app.RecordPlot.YLim, 'Color', 'b', 'LineWidth', 2);
                            app.shadedArea = patch(app.RecordPlot, ...
                                                   'XData', [pastX, pastX, xClick, xClick], ...
                                                   'YData', [app.RecordPlot.YLim(1), app.RecordPlot.YLim(2), app.RecordPlot.YLim(2), app.RecordPlot.YLim(1)], ...
                                                   'FaceColor', 'cyan', 'FaceAlpha', 0.3);
                            app.shadedArea.ButtonDownFcn = createCallbackFcn(app, @RecordPlotButtonDown, true);
                            app.record_plot_clicked = false; % Reset the click state
                        else
                            app.resetMarkOnRecordPlot();
                            app.vLine1 = line(app.RecordPlot, [xClick xClick], ...
                                          app.RecordPlot.YLim, 'Color', 'r', 'LineWidth', 2);
                        end
                    end
                end
            end
        end

        % Button pushed function: RUNSTOPButton
        function RUNSTOPButtonPushed(app, event)
            if app.isRunning % Stops updating plot
                app.resetMarkOnRecordPlot();
            end
            app.isRunning=~app.isRunning;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PortsDropDownLabel = uilabel(app.UIFigure);
            app.PortsDropDownLabel.Text = 'Ports';
            app.PortsDropDown = uidropdown(app.UIFigure);
            app.PortsDropDown.ValueChangedFcn = createCallbackFcn(app, @PortsDropDownValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RecordTab = uitab(app.TabGroup);
            app.RecordTab.Title = 'Record';
            app.RUNSTOPButton = uibutton(app.RecordTab, 'push');
            app.RUNSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @RUNSTOPButtonPushed, true);
            app.RUNSTOPButton.Text = 'RUN/STOP';
            app.AUTOSETButton = uibutton(app.RecordTab, 'push');
            app.AUTOSETButton.Text = 'AUTOSET';
            app.RECORDButton = uibutton(app.RecordTab, 'push');
            app.RECORDButton.Text = 'RECORD';
            app.VOLTSDIVKnobLabel = uilabel(app.RecordTab);
            app.VOLTSDIVKnobLabel.Text = 'VOLTS/DIV';
            app.VOLTSDIVKnob = uiknob(app.RecordTab, 'discrete');
            app.TIMEDIVKnobLabel = uilabel(app.RecordTab);
            app.TIMEDIVKnobLabel.Text = 'TIME/DIV';
            app.TIMEDIVKnob = uiknob(app.RecordTab, 'discrete');
            app.PlotPanel = uipanel(app.RecordTab);
            app.PlotPanel.Title = 'Plot';
            app.RecordPlot = uiaxes(app.PlotPanel);
            zlabel(app.RecordPlot, 'Z')
            app.RecordPlot.XTickLabel = '';
            app.RecordPlot.YTickLabel = '';
            app.RecordPlot.ButtonDownFcn = createCallbackFcn(app, @RecordPlotButtonDown, true);
            app.MinVEditFieldLabel = uilabel(app.PlotPanel);
            app.MinVEditFieldLabel.Text = 'Min [V]';
            app.MinVEditField = uieditfield(app.PlotPanel, 'numeric');
            app.MaxVEditFieldLabel = uilabel(app.PlotPanel);
            app.MaxVEditFieldLabel.Text = 'Max [V]';
            app.MaxVEditField = uieditfield(app.PlotPanel, 'numeric');
            app.FreqHzEditFieldLabel = uilabel(app.PlotPanel);
            app.FreqHzEditFieldLabel.Text = 'Freq [Hz]';
            app.FreqHzEditField = uieditfield(app.PlotPanel, 'numeric');
            app.PPAmpVEditFieldLabel = uilabel(app.PlotPanel);
            app.PPAmpVEditFieldLabel.Text = 'P-P Amp [V]';
            app.PPAmpVEditField = uieditfield(app.PlotPanel, 'numeric');
            app.SAVEButton = uibutton(app.RecordTab, 'push');
            app.SAVEButton.Text = 'SAVE';
            app.PlaybackTab = uitab(app.TabGroup);
            app.PlaybackTab.Title = 'Playback';
            app.PlaybackPlot = uiaxes(app.PlaybackTab);
            zlabel(app.PlaybackPlot, 'Z')
            app.PlaybackPlot.XTickLabel = '';
            app.PlaybackPlot.YTickLabel = '';
            app.PlayButton = uibutton(app.PlaybackTab, 'push');
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.PlaybackTab, 'push');
            app.PauseButton.Text = 'Pause';
            app.LoopSwitchLabel = uilabel(app.PlaybackTab);
            app.LoopSwitchLabel.Text = 'Loop';
            app.LoopSwitch = uiswitch(app.PlaybackTab, 'slider');
            app.Slider = uislider(app.PlaybackTab);
            app.Slider.Limits = [0 1];
            app.Slider.Value = 0.88;
            app.EditField = uieditfield(app.PlaybackTab, 'numeric');
            app.ZeroingButton = uibutton(app.PlaybackTab, 'push');
            app.ZeroingButton.Text = 'Zeroing';
            app.Lamp = uilamp(app.PlaybackTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unified_win

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end