classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Toolbar          matlab.ui.container.Toolbar
        PushTool         matlab.ui.container.toolbar.PushTool
        PauseButton      matlab.ui.control.StateButton
        AutoScaleButton  matlab.ui.control.Button
        SaveButton       matlab.ui.control.StateButton
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        dataBuffer double = []
        timestampBuffer double = []
        isRunning logical = true
        isPaused logical = false
        device % Serial device
        dataTimer % Timer for data acquisition
        plotTimer
        showLength
    end
    
    methods (Access = private)
        
        function acquireData(app)

            if ~app.isRunning
                return;
            end
        
            try
                while app.device.NumBytesAvailable > 0
                    dataLine = readline(app.device);
                    dataParts = strsplit(dataLine, ','); % Split data line at comma
                    if length(dataParts) == 2
                        millis = str2double(dataParts{1});
                        numericValue = str2double(dataParts{2});
                        if ~isnan(numericValue) && ~isnan(millis)
                            % Convert millis to elapsed seconds for timestamp
                            elapsedSeconds = millis / 1000;
                            % Append data and timestamp
                            app.dataBuffer = [app.dataBuffer, numericValue];
                            app.timestampBuffer = [app.timestampBuffer, elapsedSeconds];
                            
                            % Limit buffer to the last 1000 samples
                            if length(app.dataBuffer) > app.showLength
                                app.dataBuffer = app.dataBuffer(end-app.showLength+1:end);
                                app.timestampBuffer = app.timestampBuffer(end-app.showLength+1:end);
                            end
                        end
                    end
                end
            catch ME
                disp(['Error reading data: ', ME.message]);
            end
            if app.isRunning && ~app.isPaused
                % Your data acquisition logic here
            end
        end

        function updatePlot(app)
            if ~isempty(app.dataBuffer)
                % Update plot data
                set(app.UIAxes, 'YData', app.dataBuffer, 'XData', app.timestampBuffer); % Directly use elapsedSeconds
                drawnow limitrate; % Improve performance
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.showLength=1000;

            % Initialize serial port, timers, etc.
            app.device = serialport("/dev/cu.usbmodem101", 9600); % Example initialization
            configureTerminator(app.device, "CR/LF");
        
            % Setup and start your timers
            app.dataTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.1, ...
                'TimerFcn', @(src, event)acquireData(app));
            start(app.dataTimer);
        
            app.plotTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1, ...
                'TimerFcn', @(src, event)updatePlot(app));
            start(app.plotTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SaveButton = uibutton(app.UIFigure, 'state');
            app.SaveButton.Text = 'Save';
            app.AutoScaleButton = uibutton(app.UIFigure, 'push');
            app.AutoScaleButton.Text = 'AutoScale';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.Text = 'Pause';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end