classdef unified_win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        RecordTab                 matlab.ui.container.Tab
        Record_DButton            matlab.ui.control.Button
        Record_UButton            matlab.ui.control.Button
        RecordTIMEDIVKnob         matlab.ui.control.DiscreteKnob
        TIMEDIVKnobLabel          matlab.ui.control.Label
        Record_VOLTSDIVKnob       matlab.ui.control.DiscreteKnob
        VOLTSDIVKnobLabel         matlab.ui.control.Label
        Record_RButton            matlab.ui.control.Button
        LButton                   matlab.ui.control.Button
        Record_FreqHzEditField    matlab.ui.control.NumericEditField
        FreqHzEditField_2Label    matlab.ui.control.Label
        Record_tmsEditField       matlab.ui.control.NumericEditField
        tmsEditFieldLabel         matlab.ui.control.Label
        Record_AvgmVEditField     matlab.ui.control.NumericEditField
        AvgmVEditFieldLabel       matlab.ui.control.Label
        Record_PPAmpmVEditField   matlab.ui.control.NumericEditField
        PPAmpmVEditFieldLabel     matlab.ui.control.Label
        Record_MinmVEditField     matlab.ui.control.NumericEditField
        MinmVEditFieldLabel       matlab.ui.control.Label
        Record_MaxmVEditField     matlab.ui.control.NumericEditField
        MaxmVEditFieldLabel       matlab.ui.control.Label
        Record_RecordLamp         matlab.ui.control.Lamp
        Record_SAVEButton         matlab.ui.control.Button
        Record_RECORDButton       matlab.ui.control.Button
        AUTOSETButton             matlab.ui.control.Button
        RUNSTOPButton             matlab.ui.control.Button
        RecordPlot                matlab.ui.control.UIAxes
        PlaybackTab               matlab.ui.container.Tab
        LoopSwitch                matlab.ui.control.Switch
        LoopSwitchLabel           matlab.ui.control.Label
        MinmVEditField            matlab.ui.control.NumericEditField
        MinmVEditFieldLabel_2     matlab.ui.control.Label
        AvgmVEditField            matlab.ui.control.NumericEditField
        AvgmVEditFieldLabel_2     matlab.ui.control.Label
        FreqHzEditField_2         matlab.ui.control.NumericEditField
        FreqHzEditField_2Label_2  matlab.ui.control.Label
        PPAmpmVEditField          matlab.ui.control.NumericEditField
        PPAmpVEditField_2Label_2  matlab.ui.control.Label
        MaxmVEditField            matlab.ui.control.NumericEditField
        MaxmVEditFieldLabel_2     matlab.ui.control.Label
        tmsEditField_2            matlab.ui.control.NumericEditField
        tmsEditField_2Label       matlab.ui.control.Label
        SearchButton              matlab.ui.control.Button
        TIMEDIVKnob_2             matlab.ui.control.DiscreteKnob
        TIMEDIVKnob_2Label        matlab.ui.control.Label
        VOLTSDIVKnob_2            matlab.ui.control.DiscreteKnob
        VOLTSDIVKnob_2Label       matlab.ui.control.Label
        LButton_2                 matlab.ui.control.Button
        DButton_2                 matlab.ui.control.Button
        UButton_2                 matlab.ui.control.Button
        RButton_2                 matlab.ui.control.Button
        RUNSTOPButton_3           matlab.ui.control.Button
        FileEditField             matlab.ui.control.EditField
        FileEditFieldLabel        matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        ZeroingButton             matlab.ui.control.Button
        EditField                 matlab.ui.control.NumericEditField
        Slider                    matlab.ui.control.Slider
        RecordPlot_2              matlab.ui.control.UIAxes
        PortsDropDown             matlab.ui.control.DropDown
        PortsDropDownLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        device % The Seeeduino device

        default_port_message='Select a port'
        

        dispRecordVoltBuffer = [] % Buffer for storing data values
        dispRecordTimeBuffer = [] % Buffer for storing timestamp values

        saveVoltBuffer = []
        saveTimestampBuffer=[]
        
        
        dataTimer % Timer for data acquisition
        plotTimer % Timer for updating the plot
        showLength = 1000 % Number of points to show on the plot

        % RUN/STOP Btn Click
        isRunning logical = true % Flag to updating the plot

        % RECORD Btn
        isRecording logical = false
        
        % Click management on the plot
        record_plot_clicked logical = false % To track the number of clicks
        vLine1 % To store the handle to the first vertical line
        vLine2 % To store the handle to the second vertical line
        shadedArea % To store the handle to the shaded area patch

        % Zoom/Shift on the plot
        tTickSize
        vTickSize

        tOffsetByTick
        vOffsetByTick
        
        tTickNumDisp
        vTickNumDisp


    end
    
    methods (Access = private)
        
        function acquireData(app)
            if ~isempty(app.device)
                while app.device.NumBytesAvailable > 0
                    dataLine = readline(app.device);
                    dataParts = strsplit(dataLine, ',');
                    if length(dataParts) ~= 2
                        error('dataline is wrong, not [timestamps, volts]')
                    end

                    millis = str2double(dataParts{1});
                    numericValue = str2double(dataParts{2});
                    if ~isnan(numericValue) && ~isnan(millis)
                        elapsedMillisec = millis;
                        if app.isRunning
                            app.dispRecordVoltBuffer = [app.dispRecordVoltBuffer, numericValue];
                            app.dispRecordTimeBuffer = [app.dispRecordTimeBuffer, elapsedMillisec];
                        end

                        if app.isRecording
                            app.saveVoltBuffer=[app.saveVoltBuffer numericValue];
                            app.saveTimestampBuffer=[app.saveTimestampBuffer elapsedMillisec];
                        end
                        
                        if length(app.dispRecordVoltBuffer) > app.showLength
                            app.dispRecordVoltBuffer = app.dispRecordVoltBuffer(end-app.showLength+1:end);
                            app.dispRecordTimeBuffer = app.dispRecordTimeBuffer(end-app.showLength+1:end);
                        end
                    end
                end
            end
        end
        
        function updatePlot(app)
            if(app.isRunning && ~isempty(app.dispRecordTimeBuffer))
                app.plot();

                % Edit the features
                app.Record_tmsEditField.Value=min(app.dispRecordVoltBuffer);
                app.Record_MaxmVEditField.Value=max(app.dispRecordVoltBuffer);
                app.Record_PPAmpmVEditField.Value=app.Record_MaxmVEditField.Value-app.Record_tmsEditField.Value;
                app.Record_AvgmVEditField.Value=mean(app.dispRecordVoltBuffer);
                app.Record_FreqHzEditField.Value=app.DomFreq(app.dispRecordTimeBuffer,app.dispRecordVoltBuffer);
            end
        end

        
        function resetMarkOnRecordPlot(app)
            if isgraphics(app.vLine1, 'line')
                delete(app.vLine1);
            end
            if isgraphics(app.vLine2, 'line')
                delete(app.vLine2);
            end
            if isgraphics(app.shadedArea, 'patch')
                delete(app.shadedArea);
            end
        end
        
        function saveCSV(app, timestamps, volts)
            % Open a file save dialog allowing the user to specify a CSV file
            [file, path] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save as');
            if isequal(file, 0) || isequal(path, 0)
                disp('User clicked Cancel.');
            else
                % Construct the full path where the file will be saved
                fullPath = fullfile(path, file);
                disp(['User selected ', fullPath, ' for saving.']);
                
                % Create a table from the timestamps and volts arrays
                T = table(timestamps(:), volts(:), 'VariableNames', {'Timestamps', 'Volts'});
                
                % Write the table to a CSV file
                writetable(T, fullPath);
                disp(['Data saved to ', fullPath]);
            end
        end

        
        function freq = DomFreq(app,timestamps,volts)

            % Ensure timestamps are uniformly spaced
            dt = mean(diff(timestamps)); % Average time interval between samples
            Fs = 1000/dt % Sampling frequency
            
            % Remove the DC component by subtracting the mean
            volts = volts - mean(volts);
            
            % Compute the Fast Fourier Transform (FFT)
            Y = fft(volts);
            
            % Get the number of points
            n = length(Y);
            Y = Y(1:floor(n/2)+1); % Use only the first half of Y (positive frequencies)
            
            % Compute the frequency values corresponding to Y
            f = (0:floor(n/2)) * Fs / n;
            
            % Find the magnitude of the FFT, which represents the amplitude of each frequency component
            P = abs(Y);
            
            % Find the index of the dominant frequency (excluding the DC component)
            [~, maxIdx] = max(P(2:end)); % Start from index 2 to exclude the DC component (0 Hz)
            maxIdx = maxIdx + 1; % Adjust index because we started from the second element
            
            % Get the dominant frequency based on the index
            freq = f(maxIdx);

        end
        
       function plot(app)
            % Plot the data
                plot(app.RecordPlot, app.dispRecordTimeBuffer, app.dispRecordVoltBuffer);
                
                % Ensure there's data to work with
                if ~isempty(app.dispRecordTimeBuffer)
                    % Calculate the right limit of the x-axis based on the latest timestamp
                    lastTimestamp = app.dispRecordTimeBuffer(end);
                    
                    % Calculate the right limit of the x-axis
                    rtlim = lastTimestamp + app.tOffsetByTick * app.tTickSize;
                    
                    % Calculate the left limit of the x-axis based on the number of ticks to display and tick size
                    ltlim = rtlim - app.tTickNumDisp * app.tTickSize;
                    
                    % Set the x-axis limits
                    xlim(app.RecordPlot, [ltlim, rtlim]);

                    app.Record_MinmVEditField.Value=(min(app.dispRecordVoltBuffer)-2*min(app.dispRecordVoltBuffer))*1000;
                    app.Record_MaxmVEditField.Value=(max(app.dispRecordVoltBuffer)-2*min(app.dispRecordVoltBuffer))*1000;
                    app.Record_PPAmpmVEditField.Value=(app.Record_MaxmVEditField.Value-app.Record_MinmVEditField.Value);
                    app.Record_AvgmVEditField.Value=(mean(app.dispRecordVoltBuffer)-2*min(app.dispRecordVoltBuffer))*1000;
                    app.Record_FreqHzEditField.Value=app.DomFreq(app.dispRecordTimeBuffer,app.dispRecordVoltBuffer);

                end
                
                % Update the plot
                drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data_path='./Data/Filtered Action Potential Data/';
            
            record_file_name='Filtered_lockedopen1.csv';
            playback_file_name='Filtered_noninvasive1.csv';

            record_data_path=[data_path, record_file_name];
            playback_data_path=[data_path, playback_file_name];
            
            record_data=readtable(record_data_path)
            playback_data=readtable(playback_data_path);

            record_time_data= record_data.Time;
            record_volt_data= -record_data.FilteredVoltage;
            
            app.dispRecordTimeBuffer=record_time_data;
            app.dispRecordVoltBuffer=record_volt_data;

            % availablePorts = serialportlist("available");
            % app.PortsDropDown.Items = [app.default_port_message, availablePorts];
            % app.PortsDropDown.Value = app.default_port_message;
        
            % Initialize timers but don't start them yet
            app.dataTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.01, ...
                                  'TimerFcn', @(src, event)acquireData(app));
            app.plotTimer = timer('ExecutionMode', 'fixedRate', 'Period', 2, ...
                                  'TimerFcn', @(src, event)updatePlot(app));

            % Plot tick init
            app.tTickSize = str2double(app.RecordTIMEDIVKnob.Value);
            app.vTickSize = str2double(app.Record_VOLTSDIVKnob.Value);
            
            app.tOffsetByTick=0;
            app.vOffsetByTick=0;

            app.tTickNumDisp=10;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          
            if ~isempty(app.dataTimer)
                stop(app.dataTimer);
                delete(app.dataTimer);
            end
            if ~isempty(app.plotTimer)
                stop(app.plotTimer);
                delete(app.plotTimer);
            end
            if ~isempty(app.device)
                delete(app.device);
            end
            delete(app.UIFigure);

        end

        % Value changed function: PortsDropDown
        function PortsDropDownValueChanged(app, event)

            value = app.PortsDropDown.Value;
            if ~strcmp(value, app.default_port_message)
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                try
                    app.device = serialport(value, 9600);
                    configureTerminator(app.device, "CR/LF");
                    % Start timers when a valid port is selected and device is configured
                    start(app.dataTimer);
                    start(app.plotTimer);
                catch exception
                    uialert(app.UIFigure, exception.message, 'Serial Port Error');
                    app.PortsDropDown.Value = app.default_port_message;
                end
            else
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                % Stop timers if the default message is selected
                stop(app.dataTimer);
                stop(app.plotTimer);
            end

        end

        % Button down function: RecordPlot
        function RecordPlotButtonDown(app, event)
            % Get the current point clicked by the user
            currentPoint = app.RecordPlot.CurrentPoint;
            xClick = currentPoint(1,1); % X-coordinate of the click
            
            if ~app.isRunning % Can mark only when the plot updating pauses
                if ~app.record_plot_clicked % First click
                    app.resetMarkOnRecordPlot();
                    app.vLine1 = line(app.RecordPlot, [xClick xClick], ...
                                      app.RecordPlot.YLim, 'Color', 'r', 'LineWidth', 2);
                    app.record_plot_clicked = true;
                else % Second Click
                    if isgraphics(app.vLine1, 'line')
                        pastX = app.vLine1.XData(1);
                        if xClick > pastX
                            app.vLine2 = line(app.RecordPlot, [xClick xClick], ...
                                              app.RecordPlot.YLim, 'Color', 'b', 'LineWidth', 2);
                            app.shadedArea = patch(app.RecordPlot, ...
                                                   'XData', [pastX, pastX, xClick, xClick], ...
                                                   'YData', [app.RecordPlot.YLim(1), app.RecordPlot.YLim(2), app.RecordPlot.YLim(2), app.RecordPlot.YLim(1)], ...
                                                   'FaceColor', [0.65, 0.65, 0.65], 'FaceAlpha', 0.3);
                            app.shadedArea.ButtonDownFcn = createCallbackFcn(app, @RecordPlotButtonDown, true);
                            app.record_plot_clicked = false; % Reset the click state
                        else
                            app.resetMarkOnRecordPlot();
                            app.vLine1 = line(app.RecordPlot, [xClick xClick], ...
                                          app.RecordPlot.YLim, 'Color', 'r', 'LineWidth', 2);
                        end
                    end
                end
            end
        end

        % Button pushed function: RUNSTOPButton
        function RUNSTOPButtonPushed(app, event)
            if app.isRunning % Stops updating plot
                app.resetMarkOnRecordPlot();
            end
            app.isRunning=~app.isRunning;
        end

        % Button pushed function: Record_SAVEButton
        function Record_SAVEButtonPushed(app, event)

            if isgraphics(app.vLine1, 'line') && isgraphics(app.vLine2, 'line')
                % Extract the X coordinates of the two lines
                x1 = app.vLine1.XData(1)
                x2 = app.vLine2.XData(1)
                app.dispRecordTimeBuffer
        
                % Ensure x1 is the smaller value
                if x1 > x2
                    [x1, x2] = deal(x2, x1); % Swap x1 and x2
                end
        
                % Find the indices of the data points that fall within the range [x1, x2]
                inRangeIndices = find(app.dispRecordTimeBuffer >= x1 & app.dispRecordTimeBuffer <= x2);
        
                if ~isempty(inRangeIndices)
                    % Extract the data points that fall within the range
                    filteredTimestamps = app.dispRecordTimeBuffer(inRangeIndices);
                    filteredVolts = app.dispRecordVoltBuffer(inRangeIndices);
        
                    % Call saveCSV to save the filtered data
                    app.saveCSV(filteredTimestamps, filteredVolts);
                else
                    % Handle the case where no data falls within the selected range
                    uialert(app.UIFigure, 'No data falls within the selected range.', 'Save Error');
                end
            else
                % Handle the case where one or both lines have not been placed
                uialert(app.UIFigure, 'Please select a range by placing both lines on the plot.', 'Save Error');
            end

        end

        % Button pushed function: Record_RECORDButton
        function Record_RECORDButtonPushed(app, event)
            if app.isRecording % Stop recording and save the data
                app.saveCSV(app.saveTimestampBuffer, app.saveVoltBuffer);
                app.Record_RecordLamp.Color=[0.80,0.80,0.80];
            else % Start recording
                app.Record_RecordLamp.Color='red';
      
            end
            app.isRecording=~app.isRecording;
        end

        % Value changed function: Record_VOLTSDIVKnob
        function Record_VOLTSDIVKnobValueChanged(app, event)
            app.vTickSize = str2double(app.Record_VOLTSDIVKnob.Value);
            app.plot();
        end

        % Value changed function: RecordTIMEDIVKnob
        function RecordTIMEDIVKnobValueChanged(app, event)
            app.tTickSize = str2double(app.RecordTIMEDIVKnob.Value);
            app.plot();
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            app.tOffsetByTick=app.tOffsetByTick-1;
            app.plot();
        end

        % Button pushed function: Record_RButton
        function Record_RButtonPushed(app, event)
            app.tOffsetByTick=app.tOffsetByTick+1;
            app.plot();
        end

        % Button pushed function: Record_UButton
        function Record_UButtonPushed(app, event)
            app.vOffsetByTick=app.vOffsetByTick+1;
            app.plot();
        end

        % Button pushed function: Record_DButton
        function Record_DButtonPushed(app, event)
            app.vOffsetByTick=app.vOffsetByTick-1;
            app.plot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PortsDropDownLabel = uilabel(app.UIFigure);
            app.PortsDropDownLabel.Text = 'Ports';
            app.PortsDropDown = uidropdown(app.UIFigure);
            app.PortsDropDown.Items = {'/dev/tty.usbmodem1101', '/dev/cu.usbmodem1101'};
            app.PortsDropDown.ValueChangedFcn = createCallbackFcn(app, @PortsDropDownValueChanged, true);
            app.PortsDropDown.Value = '/dev/tty.usbmodem1101';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RecordTab = uitab(app.TabGroup);
            app.RecordTab.Title = 'Record';
            app.RecordPlot = uiaxes(app.RecordTab);
            zlabel(app.RecordPlot, 'Z')
            app.RecordPlot.XTickLabel = '';
            app.RecordPlot.YTickLabel = '';
            app.RecordPlot.ButtonDownFcn = createCallbackFcn(app, @RecordPlotButtonDown, true);
            app.RUNSTOPButton = uibutton(app.RecordTab, 'push');
            app.RUNSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @RUNSTOPButtonPushed, true);
            app.RUNSTOPButton.Text = 'RUN/STOP';
            app.AUTOSETButton = uibutton(app.RecordTab, 'push');
            app.AUTOSETButton.Text = 'AUTOSET';
            app.Record_RECORDButton = uibutton(app.RecordTab, 'push');
            app.Record_RECORDButton.ButtonPushedFcn = createCallbackFcn(app, @Record_RECORDButtonPushed, true);
            app.Record_RECORDButton.Text = 'RECORD';
            app.Record_SAVEButton = uibutton(app.RecordTab, 'push');
            app.Record_SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @Record_SAVEButtonPushed, true);
            app.Record_SAVEButton.Text = 'SAVE';
            app.Record_RecordLamp = uilamp(app.RecordTab);
            app.MaxmVEditFieldLabel = uilabel(app.RecordTab);
            app.MaxmVEditFieldLabel.Text = 'Max [mV]';
            app.Record_MaxmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.MinmVEditFieldLabel = uilabel(app.RecordTab);
            app.MinmVEditFieldLabel.Text = 'Min [mV]';
            app.Record_MinmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.PPAmpmVEditFieldLabel = uilabel(app.RecordTab);
            app.PPAmpmVEditFieldLabel.Text = 'P-P Amp [mV]';
            app.Record_PPAmpmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.AvgmVEditFieldLabel = uilabel(app.RecordTab);
            app.AvgmVEditFieldLabel.Text = 'Avg [mV]';
            app.Record_AvgmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.tmsEditFieldLabel = uilabel(app.RecordTab);
            app.tmsEditFieldLabel.Text = 'Δt [ms]';
            app.Record_tmsEditField = uieditfield(app.RecordTab, 'numeric');
            app.FreqHzEditField_2Label = uilabel(app.RecordTab);
            app.FreqHzEditField_2Label.Text = 'Freq [Hz]';
            app.Record_FreqHzEditField = uieditfield(app.RecordTab, 'numeric');
            app.LButton = uibutton(app.RecordTab, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.Record_RButton = uibutton(app.RecordTab, 'push');
            app.Record_RButton.ButtonPushedFcn = createCallbackFcn(app, @Record_RButtonPushed, true);
            app.Record_RButton.Text = 'R';
            app.VOLTSDIVKnobLabel = uilabel(app.RecordTab);
            app.VOLTSDIVKnobLabel.Text = 'VOLTS/DIV';
            app.Record_VOLTSDIVKnob = uiknob(app.RecordTab, 'discrete');
            app.Record_VOLTSDIVKnob.Items = {'10mV', '50mV', '100mV', '200mV', '500mV', '1V', '2V'};
            app.Record_VOLTSDIVKnob.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.Record_VOLTSDIVKnob.ValueChangedFcn = createCallbackFcn(app, @Record_VOLTSDIVKnobValueChanged, true);
            app.Record_VOLTSDIVKnob.Value = '200';
            app.TIMEDIVKnobLabel = uilabel(app.RecordTab);
            app.TIMEDIVKnobLabel.Text = 'TIME/DIV';
            app.RecordTIMEDIVKnob = uiknob(app.RecordTab, 'discrete');
            app.RecordTIMEDIVKnob.Items = {'1ms', '5ms', '10ms', '50ms', '100ms', '200ms', '500ms'};
            app.RecordTIMEDIVKnob.ItemsData = {'1', '5', '10', '50', '100', '200', '500'};
            app.RecordTIMEDIVKnob.ValueChangedFcn = createCallbackFcn(app, @RecordTIMEDIVKnobValueChanged, true);
            app.RecordTIMEDIVKnob.Value = '10';
            app.Record_UButton = uibutton(app.RecordTab, 'push');
            app.Record_UButton.ButtonPushedFcn = createCallbackFcn(app, @Record_UButtonPushed, true);
            app.Record_UButton.Text = 'U';
            app.Record_DButton = uibutton(app.RecordTab, 'push');
            app.Record_DButton.ButtonPushedFcn = createCallbackFcn(app, @Record_DButtonPushed, true);
            app.Record_DButton.Text = 'D';
            app.PlaybackTab = uitab(app.TabGroup);
            app.PlaybackTab.Title = 'Playback';
            app.RecordPlot_2 = uiaxes(app.PlaybackTab);
            zlabel(app.RecordPlot_2, 'Z')
            app.RecordPlot_2.XTickLabel = '';
            app.RecordPlot_2.YTickLabel = '';
            app.Slider = uislider(app.PlaybackTab);
            app.Slider.Limits = [0 1];
            app.Slider.Value = 0.88;
            app.EditField = uieditfield(app.PlaybackTab, 'numeric');
            app.ZeroingButton = uibutton(app.PlaybackTab, 'push');
            app.ZeroingButton.Text = 'Zeroing';
            app.Lamp = uilamp(app.PlaybackTab);
            app.FileEditFieldLabel = uilabel(app.PlaybackTab);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.PlaybackTab, 'text');
            app.RUNSTOPButton_3 = uibutton(app.PlaybackTab, 'push');
            app.RUNSTOPButton_3.Text = 'RUN/STOP';
            app.RButton_2 = uibutton(app.PlaybackTab, 'push');
            app.RButton_2.Text = 'R';
            app.UButton_2 = uibutton(app.PlaybackTab, 'push');
            app.UButton_2.Text = 'U';
            app.DButton_2 = uibutton(app.PlaybackTab, 'push');
            app.DButton_2.Text = 'D';
            app.LButton_2 = uibutton(app.PlaybackTab, 'push');
            app.LButton_2.Text = 'L';
            app.VOLTSDIVKnob_2Label = uilabel(app.PlaybackTab);
            app.VOLTSDIVKnob_2Label.Text = 'VOLTS/DIV';
            app.VOLTSDIVKnob_2 = uiknob(app.PlaybackTab, 'discrete');
            app.VOLTSDIVKnob_2.Items = {'10mV', '50mV', '100mV', '200mV', '500mV', '1V', '2V'};
            app.VOLTSDIVKnob_2.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.VOLTSDIVKnob_2.Value = '10';
            app.TIMEDIVKnob_2Label = uilabel(app.PlaybackTab);
            app.TIMEDIVKnob_2Label.Text = 'TIME/DIV';
            app.TIMEDIVKnob_2 = uiknob(app.PlaybackTab, 'discrete');
            app.TIMEDIVKnob_2.Items = {'10ms', '50ms', '100ms', '200ms', '500ms', '1s', '2s'};
            app.TIMEDIVKnob_2.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.TIMEDIVKnob_2.Value = '10';
            app.SearchButton = uibutton(app.PlaybackTab, 'push');
            app.SearchButton.Text = 'Search';
            app.tmsEditField_2Label = uilabel(app.PlaybackTab);
            app.tmsEditField_2Label.Text = 'Δt [ms]';
            app.tmsEditField_2 = uieditfield(app.PlaybackTab, 'numeric');
            app.MaxmVEditFieldLabel_2 = uilabel(app.PlaybackTab);
            app.MaxmVEditFieldLabel_2.Text = 'Max [mV]';
            app.MaxmVEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.PPAmpVEditField_2Label_2 = uilabel(app.PlaybackTab);
            app.PPAmpVEditField_2Label_2.Text = 'P-P Amp [mV]';
            app.PPAmpmVEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.FreqHzEditField_2Label_2 = uilabel(app.PlaybackTab);
            app.FreqHzEditField_2Label_2.Text = 'Freq [Hz]';
            app.FreqHzEditField_2 = uieditfield(app.PlaybackTab, 'numeric');
            app.AvgmVEditFieldLabel_2 = uilabel(app.PlaybackTab);
            app.AvgmVEditFieldLabel_2.Text = 'Avg [mV]';
            app.AvgmVEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.MinmVEditFieldLabel_2 = uilabel(app.PlaybackTab);
            app.MinmVEditFieldLabel_2.Text = 'Min [mV]';
            app.MinmVEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.LoopSwitchLabel = uilabel(app.PlaybackTab);
            app.LoopSwitchLabel.Text = 'Loop';
            app.LoopSwitch = uiswitch(app.PlaybackTab, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unified_win

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end